classdef SIMULATOR_PC_IC_TURBOJET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        Image                           matlab.ui.control.Image
        tau_cEditField                  matlab.ui.control.NumericEditField
        Label_39                        matlab.ui.control.Label
        M_2EditField_2                  matlab.ui.control.NumericEditField
        M_2EditField_2Label             matlab.ui.control.Label
        SimulationPerformedIdealCheckBox  matlab.ui.control.CheckBox
        ComputeidealcycleButton         matlab.ui.control.Button
        IDEALLabel                      matlab.ui.control.Label
        CYCLELabel                      matlab.ui.control.Label
        SETUPLabel                      matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_25                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        PREandINTERCOOLERcombinedLabel  matlab.ui.control.Label
        phiEditField                    matlab.ui.control.NumericEditField
        psiLabel                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        eta_cpEditField                 matlab.ui.control.NumericEditField
        eta_cpLabel                     matlab.ui.control.Label
        eta_tpEditField                 matlab.ui.control.NumericEditField
        eta_tpEditFieldLabel            matlab.ui.control.Label
        PolitropicefficienciesLabel     matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        TurbojetconfigurationLabel      matlab.ui.control.Label
        PressurelossesLabel             matlab.ui.control.Label
        epsilon_nEditField              matlab.ui.control.NumericEditField
        epsilon_nEditFieldLabel         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        epsilon_iEditField              matlab.ui.control.NumericEditField
        epsilon_iEditFieldLabel         matlab.ui.control.Label
        epsilon_bEditField_2            matlab.ui.control.NumericEditField
        epsilon_bEditField_2Label       matlab.ui.control.Label
        Tt_4EditField_3                 matlab.ui.control.NumericEditField
        Tt_4EditField_3Label            matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        KLabel_4                        matlab.ui.control.Label
        KLabel_2                        matlab.ui.control.Label
        PaLabel                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        P_0EditField                    matlab.ui.control.NumericEditField
        DeltaT_cLabel                   matlab.ui.control.Label
        T_0EditField                    matlab.ui.control.NumericEditField
        T_0EditFieldLabel               matlab.ui.control.Label
        M_0EditField                    matlab.ui.control.NumericEditField
        M_0EditFieldLabel               matlab.ui.control.Label
        EngineparametersandperformanceTab  matlab.ui.container.Tab
        PRECOOLINGPARAMETERSLabel       matlab.ui.control.Label
        M_2EditField                    matlab.ui.control.NumericEditField
        M_2EditField_3Label             matlab.ui.control.Label
        W_skJEditField                  matlab.ui.control.NumericEditField
        W_skJEditFieldLabel             matlab.ui.control.Label
        P_t25EditField_2                matlab.ui.control.NumericEditField
        P_t25Label                      matlab.ui.control.Label
        P_t25EditField                  matlab.ui.control.NumericEditField
        P_t25EditFieldLabel             matlab.ui.control.Label
        T_t25EditField_2                matlab.ui.control.NumericEditField
        T_t25Label                      matlab.ui.control.Label
        T_t25EditField                  matlab.ui.control.NumericEditField
        T_t25EditFieldLabel             matlab.ui.control.Label
        INTERCOOLINGPARAMETERSLabel     matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        V_0Label                        matlab.ui.control.Label
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_24                    matlab.ui.control.NumericEditField
        T_t4Label                       matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        kPaLabel                        matlab.ui.control.Label
        KLabel_8                        matlab.ui.control.Label
        EfficienciesLabel               matlab.ui.control.Label
        EditField_23                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_20                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        V_9Label                        matlab.ui.control.Label
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        EditField_10                    matlab.ui.control.NumericEditField
        EditField_9                     matlab.ui.control.NumericEditField
        EditField_8                     matlab.ui.control.NumericEditField
        EditField_7                     matlab.ui.control.NumericEditField
        EditField_6                     matlab.ui.control.NumericEditField
        EditField_5                     matlab.ui.control.NumericEditField
        EditField_4                     matlab.ui.control.NumericEditField
        EditField_3                     matlab.ui.control.NumericEditField
        EditField_2                     matlab.ui.control.NumericEditField
        EditField                       matlab.ui.control.NumericEditField
        pi_9Label                       matlab.ui.control.Label
        P_t9Label                       matlab.ui.control.Label
        T_9Label                        matlab.ui.control.Label
        alphaLabel                      matlab.ui.control.Label
        P_t5Label                       matlab.ui.control.Label
        T_t5Label                       matlab.ui.control.Label
        pi_cLabel                       matlab.ui.control.Label
        P_t4Label                       matlab.ui.control.Label
        alpha_bLabel                    matlab.ui.control.Label
        P_t3Label                       matlab.ui.control.Label
        T_t3Label                       matlab.ui.control.Label
        P_t2Label                       matlab.ui.control.Label
        T_t2Label                       matlab.ui.control.Label
        pi_fsLabel                      matlab.ui.control.Label
        P_t0Label                       matlab.ui.control.Label
        T_t0Label                       matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        ThermalLabel                    matlab.ui.control.Label
        PropulsiveLabel                 matlab.ui.control.Label
        OverallLabel                    matlab.ui.control.Label
        DeltaK_ckJLabel                 matlab.ui.control.Label
        C_TSkJgLabel                    matlab.ui.control.Label
        psimsLabel                      matlab.ui.control.Label
        PerformancesLabel               matlab.ui.control.Label
        TrendsTab                       matlab.ui.container.Tab
        UIAxes5                         matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        UIAxes4                         matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
       
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeidealcycleButton
        function ComputeidealcycleButtonPushed(app, event)
            cla(app.UIAxes, 'reset'); % Clear axes control.
            cla(app.UIAxes2, 'reset');
            cla(app.UIAxes3, 'reset');
            cla(app.UIAxes4, 'reset');
            cla(app.UIAxes5, 'reset');
            cla(app.UIAxes2_2, 'reset');
            M_0= app.M_0EditField.Value;
            P_0=app.P_0EditField.Value;
            T_0=app.T_0EditField.Value;
            Tt4= app.Tt_4EditField_3.Value;
            e_i=app.epsilon_iEditField.Value;
            e_b=app.epsilon_bEditField_2.Value;
            e_n=app.epsilon_nEditField.Value;
            eta_cp=app.eta_cpEditField.Value;
            eta_tp=app.eta_tpEditField.Value;

            M_2=app.M_2EditField_2.Value;
            tau_c =app.tau_cEditField.Value;
            
            e_interp=0;
            diff_T=0;
            Delta_T_Pc_values=0;
            
            bleed=app.xEditField.Value;
            nozzle_velocity_coeff=app.phiEditField.Value;
            gamma=1.4;
            r=287.15;
            alpha=0;
            Tt3_prima_val=0;
            Tt25_p_val=0;
            
            [specific_thrust,overall_eff,C_ts,prop_eff,thermal_eff,Deltak_c,w_s,Tt0,Pt0,pi_fs,Tt2,Pt2,Tt25,Pt25,Tt25_p,Pt25_p,Tt3_prima,Pt3,pi_c,Pt4,alpha_b,Tt5,Pt5,alpha_prima,T9,Pt9,pi_9,V9,V0] = CALCULATION_IC_PC_ID(M_0,T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val,Delta_T_Pc_values,eta_cp,e_b,Tt4,bleed,eta_tp,nozzle_velocity_coeff,e_n,e_interp,diff_T,Tt25_p_val,M_2,tau_c);
            
       
            %app.UITable.RowName={'Specific Thrust','C_{TS}','\Delta K_{c}'};


            app.EditField_18.Value=specific_thrust;
            app.EditField_19.Value=C_ts*1000000;
            app.EditField_20.Value=Deltak_c/1000;
            app.W_skJEditField.Value = w_s/1000;
            
            %app.UITable_2.RowName={'Overall efficiency', 'Propulsive efficiency','Thermal efficiency'};
            app.EditField_21.Value=overall_eff;
            app.EditField_22.Value=prop_eff;
            app.EditField_23.Value=thermal_eff;
            app.EditField_24.Value=Tt4;
            app.EditField_25.Value=V0;

            app.T_t25EditField.Value=Tt25;
            app.T_t25EditField_2.Value=Tt25_p;
            app.P_t25EditField.Value=Pt25/1000;
            app.P_t25EditField_2.Value=Pt25_p/1000;
            
            app.EditField.Value = Tt0;
            app.EditField_2.Value  = Pt0/1000;
            app.EditField_3.Value  = pi_fs;
            app.EditField_4.Value  = Tt2;
            app.EditField_5.Value  = Pt2/1000;
            app.EditField_6.Value  = Tt3_prima;
            app.EditField_7.Value  = Pt3/1000;
            app.EditField_8.Value  = pi_c;
            app.EditField_9.Value  = Pt4/1000;
            app.EditField_10.Value  = alpha_b;
            app.EditField_11.Value  = Tt5;
            app.EditField_12.Value  = Pt5/1000;
            app.EditField_13.Value  = alpha_prima;
            app.EditField_14.Value  = T9;
            app.EditField_15.Value  = Pt9/1000;
            app.EditField_16.Value  = pi_9;
            app.EditField_17.Value  = V9;
            app.M_2EditField.Value = M_2;

            M0_values=[M_0-1,M_0];
%             Delta_Tc_values=0:300:900;
            Tt4_values=1800:300:2400;
            Tt3_prima_val=Tt3_prima-200:1:Tt3_prima+1450;
            Delta_T_Pc_Values=0;
            Tt25_p_val = Tt0-390:1:Tt25+300;

            specific_thrust_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_real=zeros(1,length(Tt3_prima_val));

            i_1=1;
            while or(specific_thrust_vec_M0_fixed_real(i_1)>0,i_1<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_real(i_1),overall_eff_vec_M0_fixed_real(i_1),C_ts_vec_M0_fixed_real(i_1),prop_eff_vec_M0_fixed_real(i_1),thermal_eff_vec_M0_fixed_real(i_1),deltak_c,W_vec_M0_fixed_real(i_1),~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~] = CALCULATION_IC_PC_ID(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_1),Delta_T_Pc_Values,eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,0,M_2,tau_c);
                i_1=i_1+1;
                
            end

            specific_thrust_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            overall_eff_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            C_ts_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            prop_eff_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            thermal_eff_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            W_vec_M0_fixed_Tt25_p=zeros(1,length(Tt25_p_val));
            V9_Tt25_p_val=zeros(1,length(Tt25_p_val));

            i_2=1;
            while or(specific_thrust_vec_M0_fixed_Tt25_p(i_2)>0,i_2<length(Tt25_p_val)) 
                [specific_thrust_vec_M0_fixed_Tt25_p(i_2),overall_eff_vec_M0_fixed_Tt25_p(i_2),C_ts_vec_M0_fixed_Tt25_p(i_2),prop_eff_vec_M0_fixed_Tt25_p(i_2),thermal_eff_vec_M0_fixed_Tt25_p(i_2),deltak_c,W_vec_M0_fixed_Tt25_p(i_2),~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,V9_Tt25_p_val(i_2),~] = CALCULATION_IC_PC_ID(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,0,Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,Tt25_p_val(i_2),M_2,tau_c);
                i_2=i_2+1;
                
            end
            
            specific_thrust_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_theta4down=zeros(1,length(Tt3_prima_val));

            i_3=1;
            while or(specific_thrust_vec_M0_fixed_theta4down(i_3)>0,i_3<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_theta4down(i_3),overall_eff_vec_M0_fixed_theta4down(i_3),C_ts_vec_M0_fixed_theta4down(i_3),prop_eff_vec_M0_fixed_theta4down(i_3),thermal_eff_vec_M0_fixed_theta4down(i_3),deltak_c,W_vec_M0_fixed_theta4down(i_3)] = CALCULATION_IC_PC_ID(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_3),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(1),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,0,M_2,tau_c);
                i_3=i_3+1;

            end
            
            specific_thrust_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            overall_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            C_ts_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            prop_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            thermal_eff_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            W_vec_M0_fixed_theta0down=zeros(1,length(Tt3_prima_val));
            
            i_4=1;
            while or(specific_thrust_vec_M0_fixed_theta0down(i_4)>0 ,i_4<length(Tt3_prima_val)) 
                [specific_thrust_vec_M0_fixed_theta0down(i_4),overall_eff_vec_M0_fixed_theta0down(i_4),C_ts_vec_M0_fixed_theta0down(i_4),prop_eff_vec_M0_fixed_theta0down(i_4),thermal_eff_vec_M0_fixed_theta0down(i_4),deltak_c,W_vec_M0_fixed_theta0down(i_4)] = CALCULATION_IC_PC_ID(M0_values(1),T_0,gamma,r,alpha,P_0,e_i,Tt3_prima_val(i_4),Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,0,M_2,tau_c);
                i_4=i_4+1;
                
            end

            M_2_val = 1:0.01:M_0;
            specific_thrust_vec_M2_val=zeros(1,length(M_2_val));
            overall_eff_vec_M2_val=zeros(1,length(M_2_val));
            C_ts_vec_M2_val=zeros(1,length(M_2_val));
            prop_eff_vec_M2_val=zeros(1,length(M_2_val));
            thermal_eff_vec_M2_val=zeros(1,length(M_2_val));
            W_vec_M2_val=zeros(1,length(M_2_val));
            
            i_5=1;
            while or(specific_thrust_vec_M2_val(i_5)>0,i_5<length(M_2_val)) 
                [specific_thrust_vec_M2_val(i_5),overall_eff_vec_M2_val(i_5),C_ts_vec_M2_val(i_5),prop_eff_vec_M2_val(i_5),thermal_eff_vec_M2_val(i_5),deltak_c,W_vec_M2_val(i_5),~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~,~] = CALCULATION_IC_PC_ID(M0_values(2),T_0,gamma,r,alpha,P_0,e_i,0,Delta_T_Pc_Values(1),eta_cp,e_b,Tt4_values(2),bleed,eta_tp,nozzle_velocity_coeff,e_n,0,0,0,M_2_val(i_5),tau_c);
                i_5=i_5+1;
                
            end


            %% Graphs correction
            r1=0;r2=0;r3=0;r4=0;r5=0;r6=0;r7=0;r8=0;r9=0;r10=0;r11=0;r12=0;
            r13=0;r14=0;r15=0;r16=0;r17=0;r18=0;r19=0;r20=0;r21=0;r22=0;r23=0;r24=0;r25=0;r26=0;r27=0;r28=0;
            for j=1:length(Tt25_p_val)
                if j>=length(Tt25_p_val)
                    specific_thrust_vec_M0_fixed_Tt25_p(j)=NaN;
                    W_vec_M0_fixed_Tt25_p(j)=NaN;
                end
            end

            for i=1:length(prop_eff_vec_M0_fixed_real)
                
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r21==1)
                    if r21==0
                        specific_thrust_vec_M0_fixed_real(i)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r21=1;
                    else
                        specific_thrust_vec_M0_fixed_real(i)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_theta4down(i)<=0,r23==1)
                    if r23==0
                        specific_thrust_vec_M0_fixed_theta4down(i)=0;
    %                     W_vec_M0_fixed_theta4down(i)=W_vec_M0_fixed_theta4down(i-1);
                        r23=1;
                    else
                        specific_thrust_vec_M0_fixed_theta4down(i)=NaN;
    %                     W_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end
            
            
                if or(specific_thrust_vec_M0_fixed_theta0down(i)<=0,r25==1)
                    if r25==0
                        specific_thrust_vec_M0_fixed_theta0down(i)=0;
    %                     W_vec_M0_fixed_theta0down(i)=W_vec_M0_fixed_theta0down(i-1);
                        r25=1;
                    else
                        specific_thrust_vec_M0_fixed_theta0down(i)=NaN;
    %                     W_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)<=0,r7==1)
                    if r7==0
                        thermal_eff_vec_M0_fixed_real(i)=0;
                        r7=1;
                    else
                        thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_real(i)>=1,r2==1)
                    if r2==0
                        thermal_eff_vec_M0_fixed_real(i)=1;
                        r2=1;
%                     else
%                         thermal_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta4down(i)<=0,r17==1)
                    if r17==0
                        thermal_eff_vec_M0_fixed_theta4down(i)=0;
                        r17=1;
                    else
                        thermal_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta4down(i)>=1,r3==1)
                    if r3==0
                        thermal_eff_vec_M0_fixed_theta4down(i)=1;
                        r3=1;
%                     else
%                         thermal_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(thermal_eff_vec_M0_fixed_theta0down(i)<=0,r18==1)
                    if r18==0
                        thermal_eff_vec_M0_fixed_theta0down(i)=0;
                        r18=1;
                    else
                        thermal_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
       
                if or(thermal_eff_vec_M0_fixed_theta0down(i)>=1,r5==1)
                    if r5==0
                        thermal_eff_vec_M0_fixed_theta0down(i)=1;
                        r5=1;
%                     else
%                         thermal_eff_vec_M0_fixed_theta0down(i)=1;
                    end
                end

                if or(C_ts_vec_M0_fixed_real(i)<=0,r4==0)
                    C_ts_vec_M0_fixed_real(i)=NaN;
                    r4=1;
                end
    
                if or(C_ts_vec_M0_fixed_theta4down(i)<=0,r13==0)
                    C_ts_vec_M0_fixed_theta4down(i)=NaN;
                    r13=1;
                end
                
                if Tt3_prima_val(i)>= Tt4_values(1)
                    C_ts_vec_M0_fixed_theta4down(i)=NaN;
                end

                if or(C_ts_vec_M0_fixed_theta0down(i)<=0,r14==0)
                    C_ts_vec_M0_fixed_theta0down(i)=NaN;
                    r14=1;
                end

                if or(prop_eff_vec_M0_fixed_real(i)>=1,r1==1)
                    if r1==0
                        prop_eff_vec_M0_fixed_real(i)=1;
                        r1=1;
                    else
                        prop_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(prop_eff_vec_M0_fixed_theta4down(i)>=1,r15==1)
                    if r15==0
                        prop_eff_vec_M0_fixed_theta4down(i)=1;
                        r15=1;
                    else
                        prop_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(prop_eff_vec_M0_fixed_theta0down(i)>=1,r16==1)
                    if r16==0
                        prop_eff_vec_M0_fixed_theta0down(i)=1;
                        r16=1;
                    else
                        prop_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
    
                if or(specific_thrust_vec_M0_fixed_real(i)<=0,r10==1)
                    if r10==0
                        overall_eff_vec_M0_fixed_real(i)=0;
                        r10=1;
                    else
                        overall_eff_vec_M0_fixed_real(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_real(i)>=1,r6==1)
                    if r6==0
                        overall_eff_vec_M0_fixed_real(i)=1;
                        r6=1;
%                     else
%                         overall_eff_vec_M0_fixed_real(i)=1;
                    end
                end
                
                if or(specific_thrust_vec_M0_fixed_theta4down(i)<=0,r19==1)
                    if r19==0
                        overall_eff_vec_M0_fixed_theta4down(i)=0;
                        r19=1;
                    else
                        overall_eff_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_theta4down(i)>=1,r8==1)
                    if r8==0
                        overall_eff_vec_M0_fixed_theta4down(i)=1;
                        r8=1;
%                     else
%                         overall_eff_vec_M0_fixed_theta4down(i)=1;
                    end
                end

                if or(specific_thrust_vec_M0_fixed_theta0down(i)<=0,r20==1)
                    if r20==0
                        overall_eff_vec_M0_fixed_theta0down(i)=0;
                        r20=1;
                    else
                        overall_eff_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end

                if or(overall_eff_vec_M0_fixed_theta0down(i)>=1,r9==1)
                    if r9==0
                        overall_eff_vec_M0_fixed_theta0down(i)=1;
                        r9=1;
%                     else
%                         overall_eff_vec_M0_fixed_theta0down(i)=1;
                    end
                end
                
                
                if or( W_vec_M0_fixed_real(i)>=0,r26==1)
                    if r26==0
                        W_vec_M0_fixed_real(i)=0;
                        r26=1;
                    else
                        W_vec_M0_fixed_real(i)=NaN;
                    end
                end
                if or( W_vec_M0_fixed_theta4down(i)>=0,r27==1)
                    if r27==0
                        W_vec_M0_fixed_theta4down(i)=0;
                        r27=1;
                    else
                        W_vec_M0_fixed_theta4down(i)=NaN;
                    end
                end
                if or( W_vec_M0_fixed_theta0down(i)>=0,r28==1)
                    if r28==0
                        W_vec_M0_fixed_theta0down(i)=0;
                        r28=1;
                    else
                        W_vec_M0_fixed_theta0down(i)=NaN;
                    end
                end
                
            end


            for j=1:length(prop_eff_vec_M2_val)
                
                if or(specific_thrust_vec_M2_val(j)<=0,r5==1)
                    if r5==0
                        specific_thrust_vec_M2_val(j)=0;
    %                     W_vec_M0_fixed_real(i)=W_vec_M0_fixed_real(i-1);
                        r5=1;
                    else
                        specific_thrust_vec_M2_val(j)=NaN;
    %                     W_vec_M0_fixed_real(i)=NaN;
                    end
                end


                if or(thermal_eff_vec_M2_val(j)<=0,r8==1)
                    if r8==0
                        thermal_eff_vec_M2_val(j)=0;
                        r8=1;
                    else
                        thermal_eff_vec_M2_val(j)=NaN;
                    end
                end

                if or(thermal_eff_vec_M2_val(j)>=1,r9==1)
                    thermal_eff_vec_M2_val(j)=1;
                end

                if j>1
                    if or (thermal_eff_vec_M2_val(j)>=1 && thermal_eff_vec_M2_val(j-1)>=1,thermal_eff_vec_M2_val(j)>=1 && isnan(thermal_eff_vec_M2_val(j-1)))
                        thermal_eff_vec_M2_val(j)= NaN;
                    end
                else
                    if thermal_eff_vec_M2_val(j)>=1
                        thermal_eff_vec_M2_val(j)= NaN;
                    end
                end
                if or(C_ts_vec_M2_val(j)<=0,r11==0)
                    C_ts_vec_M2_val(j)=NaN;
                    r11=1;
                end


                if or(prop_eff_vec_M2_val(j)>=1,r12==1)
                    if r12==0
                        prop_eff_vec_M2_val(j)=1;
                        r12=1;
                    else
                        prop_eff_vec_M2_val(j)=NaN;
                    end
                end

    
                if or(overall_eff_vec_M2_val(j)<=0,r13==1)
                    if r13==0
                        overall_eff_vec_M2_val(j)=0;
                        r13=1;
                    else
                        overall_eff_vec_M2_val(j)=NaN;
                    end
                end

                if j>1
                    if or (overall_eff_vec_M2_val(j)>=1 && overall_eff_vec_M2_val(j-1)>=1,overall_eff_vec_M2_val(j)>=1 && isnan(overall_eff_vec_M2_val(j-1)))
                        overall_eff_vec_M2_val(j)= NaN;
                    end
                else
                    if overall_eff_vec_M2_val(j)>=1
                        overall_eff_vec_M2_val(j)= NaN;
                    end
                end

                if or( W_vec_M2_val(j)>=0,r27==1)
                    if r27==0
                        W_vec_M2_val(j)=0;
                        r27=1;
                    else
                        W_vec_M2_val(j)=NaN;
                    end
                end
                
            end


            [maximo_y, indice_maximo] = max(specific_thrust_vec_M0_fixed_real);
            x_maximo = Tt3_prima_val(indice_maximo);

            [ymax4down, indice_4down] = max(specific_thrust_vec_M0_fixed_theta4down);
            xmax4down = Tt3_prima_val(indice_4down);

            [ymax0down, indice_0down] = max(specific_thrust_vec_M0_fixed_theta0down);
            xmax0down = Tt3_prima_val(indice_0down);
            plot(app.UIAxes,Tt3_prima_val,specific_thrust_vec_M0_fixed_real,'k-',Tt3_prima_val,specific_thrust_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,specific_thrust_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes,'on');
            plot(app.UIAxes,x_maximo,maximo_y,'ko','MarkerSize',5);

            plot(app.UIAxes,xmax4down,ymax4down,'ro','MarkerSize',5);

            plot(app.UIAxes,xmax0down,ymax0down,'bo','MarkerSize',5);
            legend(app.UIAxes,'Real','\theta_4 down','\theta_0 down');
            title(app.UIAxes,'Specific thrust (\psi) - T_{t3p} trends');
            xlabel(app.UIAxes,'T_{t3p} [K]');
            ylabel(app.UIAxes,'\psi [m/s]');
            hold(app.UIAxes,'off');

            [maximo_y_2, indice_maximo_2] = max(overall_eff_vec_M0_fixed_real);
            x_maximo_2 = Tt3_prima_val(indice_maximo_2);

            [ymax4down_2, indice_4down_2] = max(overall_eff_vec_M0_fixed_theta4down);
            xmax4down_2 = Tt3_prima_val(indice_4down_2);

            [ymax0down_2, indice_0down_2] = max(overall_eff_vec_M0_fixed_theta0down);
            xmax0down_2 = Tt3_prima_val(indice_0down_2);
            plot(app.UIAxes3,Tt3_prima_val,overall_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,overall_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,overall_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes3,'on');
            plot(app.UIAxes3,x_maximo_2,maximo_y_2,'ko','MarkerSize',5);

            plot(app.UIAxes3,xmax4down_2,ymax4down_2,'ro','MarkerSize',5);

            plot(app.UIAxes3,xmax0down_2,ymax0down_2,'bo','MarkerSize',5);
            title(app.UIAxes3,'Overall efficiency (\eta_{O}) - T_{t3p} trends');
            xlabel(app.UIAxes3,'T_{t3p} [K]');
            ylabel(app.UIAxes3,'\eta_{O} [-]');
            hold(app.UIAxes3,'off');

            [maximo_y_3, indice_maximo_3] = max(thermal_eff_vec_M0_fixed_real);
            x_maximo_3 = Tt3_prima_val(indice_maximo_3);

            [ymax4down_3, indice_4down_3] = max(thermal_eff_vec_M0_fixed_theta4down);
            xmax4down_3 = Tt3_prima_val(indice_4down_3);

            [ymax0down_3, indice_0down_3] = max(thermal_eff_vec_M0_fixed_theta0down);
            xmax0down_3 = Tt3_prima_val(indice_0down_3);
            plot(app.UIAxes2,Tt3_prima_val,thermal_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,thermal_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,thermal_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes2,'on');
            plot(app.UIAxes2,x_maximo_3,maximo_y_3,'ko','MarkerSize',5);

            plot(app.UIAxes2,xmax4down_3,ymax4down_3,'ro','MarkerSize',5);

            plot(app.UIAxes2,xmax0down_3,ymax0down_3,'bo','MarkerSize',5);
            title(app.UIAxes2,'Thermal efficiency (\eta_{th}) - T_{t3p} trends')
            xlabel(app.UIAxes2,'T_{t3p} [K]')
            ylabel(app.UIAxes2,'\eta_{th} [-]')
            hold(app.UIAxes2,'off');

            [minimo_y, indice_minimo] = min(prop_eff_vec_M0_fixed_real);
            x_minimo = Tt3_prima_val(indice_minimo);

            [ymin4down, indice_4down_4] = min(prop_eff_vec_M0_fixed_theta4down);
            xmin4down = Tt3_prima_val(indice_4down_4);

            [ymin0down, indice_0down_4] = min(prop_eff_vec_M0_fixed_theta0down);
            xmin0down = Tt3_prima_val(indice_0down_4);
            plot(app.UIAxes4,Tt3_prima_val,prop_eff_vec_M0_fixed_real,'k-',Tt3_prima_val,prop_eff_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,prop_eff_vec_M0_fixed_theta0down,'b--');

            hold(app.UIAxes4,'on');
            plot(app.UIAxes4,x_minimo,minimo_y,'ko','MarkerSize',5);

            plot(app.UIAxes4,xmin4down,ymin4down,'ro','MarkerSize',5);

            plot(app.UIAxes4,xmin0down,ymin0down,'bo','MarkerSize',5);
            title(app.UIAxes4,'Propulsive efficiency (\eta_{pr}) - T_{t3} trends')
            xlabel(app.UIAxes4,'T_{t3} [K]')
            ylabel(app.UIAxes4,'\eta_{pr} [-]')
            hold(app.UIAxes4,'off');
            
            plot(app.UIAxes2_2,Tt3_prima_val,C_ts_vec_M0_fixed_real,'k-',Tt3_prima_val,C_ts_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,C_ts_vec_M0_fixed_theta0down,'b--');

            title(app.UIAxes2_2,'CTS - T_{t3} trends')
            xlabel(app.UIAxes2_2,'T_{t3} [K]')
            ylabel(app.UIAxes2_2,'C_{TS} [J/Kg]')
            hold(app.UIAxes2_2);

            [minimo_y_4, indice_minimo_4] = min(W_vec_M0_fixed_real);
            x_minimo_4 = Tt3_prima_val(indice_minimo_4);
            [ymin4down_2, indice_4down_4_2] = min(W_vec_M0_fixed_theta4down);
            xmin4down_2 = Tt3_prima_val(indice_4down_4_2);
            [ymin0down_2, indice_0down_4_2] = min(W_vec_M0_fixed_theta0down);
            xmin0down_2 = Tt3_prima_val(indice_0down_4_2);

            plot(app.UIAxes5,Tt3_prima_val,W_vec_M0_fixed_real,'k-',Tt3_prima_val,W_vec_M0_fixed_theta4down,'r--',Tt3_prima_val,W_vec_M0_fixed_theta0down,'b--');
            hold(app.UIAxes5,'on');
            plot(app.UIAxes5,x_minimo_4,minimo_y_4,'ko','MarkerSize',5);
            plot(app.UIAxes5,xmin4down_2,ymin4down_2,'ro','MarkerSize',5);
            plot(app.UIAxes5,xmin0down_2,ymin0down_2,'bo','MarkerSize',5);
            title(app.UIAxes5,'Work specific output - T_{t3} trends')
            xlabel(app.UIAxes5,'T_{t3} [K]')
            ylabel(app.UIAxes5,'W_s [kJ]')
            hold(app.UIAxes5,'off');

            app.SimulationPerformedIdealCheckBox.Value = ones;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Set up';
            app.M_0EditFieldLabel = uilabel(app.SetupTab);
            app.M_0EditFieldLabel.Interpreter = 'latex';
            app.M_0EditFieldLabel.Text = '$M_0$';
            app.M_0EditField = uieditfield(app.SetupTab, 'numeric');
            app.M_0EditField.Value = 5;
            app.T_0EditFieldLabel = uilabel(app.SetupTab);
            app.T_0EditFieldLabel.Interpreter = 'latex';
            app.T_0EditFieldLabel.Text = '$T_0$';
            app.T_0EditField = uieditfield(app.SetupTab, 'numeric');
            app.T_0EditField.Value = 216.5;
            app.DeltaT_cLabel = uilabel(app.SetupTab);
            app.DeltaT_cLabel.Interpreter = 'latex';
            app.DeltaT_cLabel.Text = '$P_{0}$';
            app.P_0EditField = uieditfield(app.SetupTab, 'numeric');
            app.P_0EditField.Value = 22632;
            app.Label = uilabel(app.SetupTab);
            app.Label.Interpreter = 'latex';
            app.Label.Text = '[-]';
            app.PaLabel = uilabel(app.SetupTab);
            app.PaLabel.Interpreter = 'latex';
            app.PaLabel.Text = '[Pa]';
            app.KLabel_2 = uilabel(app.SetupTab);
            app.KLabel_2.Interpreter = 'latex';
            app.KLabel_2.Text = '[K]';
            app.KLabel_4 = uilabel(app.SetupTab);
            app.KLabel_4.Interpreter = 'latex';
            app.KLabel_4.Text = '[K]';
            app.Label_2 = uilabel(app.SetupTab);
            app.Label_2.Interpreter = 'latex';
            app.Label_2.Text = '[-]';
            app.Label_3 = uilabel(app.SetupTab);
            app.Label_3.Interpreter = 'latex';
            app.Label_3.Text = '[-]';
            app.Tt_4EditField_3Label = uilabel(app.SetupTab);
            app.Tt_4EditField_3Label.Interpreter = 'latex';
            app.Tt_4EditField_3Label.Text = '$Tt_4$';
            app.Tt_4EditField_3 = uieditfield(app.SetupTab, 'numeric');
            app.Tt_4EditField_3.Value = 2100;
            app.epsilon_bEditField_2Label = uilabel(app.SetupTab);
            app.epsilon_bEditField_2Label.Interpreter = 'latex';
            app.epsilon_bEditField_2Label.Text = '$\epsilon_b$';
            app.epsilon_bEditField_2 = uieditfield(app.SetupTab, 'numeric');
            app.epsilon_iEditFieldLabel = uilabel(app.SetupTab);
            app.epsilon_iEditFieldLabel.Interpreter = 'latex';
            app.epsilon_iEditFieldLabel.Text = '$\epsilon_i$';
            app.epsilon_iEditField = uieditfield(app.SetupTab, 'numeric');
            app.Label_4 = uilabel(app.SetupTab);
            app.Label_4.Interpreter = 'latex';
            app.Label_4.Text = '[-]';
            app.epsilon_nEditFieldLabel = uilabel(app.SetupTab);
            app.epsilon_nEditFieldLabel.Interpreter = 'latex';
            app.epsilon_nEditFieldLabel.Text = '$\epsilon_n$';
            app.epsilon_nEditField = uieditfield(app.SetupTab, 'numeric');
            app.PressurelossesLabel = uilabel(app.SetupTab);
            app.PressurelossesLabel.Text = 'Pressure losses:';
            app.TurbojetconfigurationLabel = uilabel(app.SetupTab);
            app.TurbojetconfigurationLabel.Text = 'Turbojet configuration:';
            app.Label_10 = uilabel(app.SetupTab);
            app.Label_10.Interpreter = 'latex';
            app.Label_10.Text = '[-]';
            app.Label_11 = uilabel(app.SetupTab);
            app.Label_11.Interpreter = 'latex';
            app.Label_11.Text = '[-]';
            app.PolitropicefficienciesLabel = uilabel(app.SetupTab);
            app.PolitropicefficienciesLabel.Text = 'Politropic efficiencies:';
            app.eta_tpEditFieldLabel = uilabel(app.SetupTab);
            app.eta_tpEditFieldLabel.Interpreter = 'latex';
            app.eta_tpEditFieldLabel.Text = '$\eta_{tp}$';
            app.eta_tpEditField = uieditfield(app.SetupTab, 'numeric');
            app.eta_tpEditField.Value = 1;
            app.eta_cpLabel = uilabel(app.SetupTab);
            app.eta_cpLabel.Interpreter = 'latex';
            app.eta_cpLabel.Text = '$\eta_{cp}$';
            app.eta_cpEditField = uieditfield(app.SetupTab, 'numeric');
            app.eta_cpEditField.Value = 1;
            app.Label_12 = uilabel(app.SetupTab);
            app.Label_12.Interpreter = 'latex';
            app.Label_12.Text = '[-]';
            app.xEditFieldLabel = uilabel(app.SetupTab);
            app.xEditFieldLabel.Interpreter = 'latex';
            app.xEditFieldLabel.Text = '$x$';
            app.xEditField = uieditfield(app.SetupTab, 'numeric');
            app.Label_13 = uilabel(app.SetupTab);
            app.Label_13.Interpreter = 'latex';
            app.Label_13.Text = '[-]';
            app.psiLabel = uilabel(app.SetupTab);
            app.psiLabel.Interpreter = 'latex';
            app.psiLabel.Text = '$\phi$';
            app.phiEditField = uieditfield(app.SetupTab, 'numeric');
            app.phiEditField.Value = 1;
            app.PREandINTERCOOLERcombinedLabel = uilabel(app.SetupTab);
            app.PREandINTERCOOLERcombinedLabel.Text = 'PRE and INTER-COOLER combined';
            app.Label_23 = uilabel(app.SetupTab);
            app.Label_23.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Label_25 = uilabel(app.SetupTab);
            app.Label_25.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.Label_26 = uilabel(app.SetupTab);
            app.Label_26.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _';
            app.Label_29 = uilabel(app.SetupTab);
            app.Label_29.Text = '_ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ _ ';
            app.SETUPLabel = uilabel(app.SetupTab);
            app.SETUPLabel.Text = 'SET-UP';
            app.CYCLELabel = uilabel(app.SetupTab);
            app.CYCLELabel.Text = 'CYCLE';
            app.IDEALLabel = uilabel(app.SetupTab);
            app.IDEALLabel.Text = 'IDEAL';
            app.ComputeidealcycleButton = uibutton(app.SetupTab, 'push');
            app.ComputeidealcycleButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeidealcycleButtonPushed, true);
            app.ComputeidealcycleButton.Text = 'Compute ideal cycle';
            app.SimulationPerformedIdealCheckBox = uicheckbox(app.SetupTab);
            app.SimulationPerformedIdealCheckBox.Enable = 'off';
            app.SimulationPerformedIdealCheckBox.Text = 'Simulation Performed';
            app.M_2EditField_2Label = uilabel(app.SetupTab);
            app.M_2EditField_2Label.Interpreter = 'latex';
            app.M_2EditField_2Label.Text = '$M_2$';
            app.M_2EditField_2 = uieditfield(app.SetupTab, 'numeric');
            app.M_2EditField_2.Value = 3.3;
            app.Label_39 = uilabel(app.SetupTab);
            app.Label_39.Interpreter = 'latex';
            app.Label_39.Text = '$\tau_c$';
            app.tau_cEditField = uieditfield(app.SetupTab, 'numeric');
            app.tau_cEditField.Value = 2;
            app.Image = uiimage(app.SetupTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'TurbojetwithPre-Intercooling.png');
            app.EngineparametersandperformanceTab = uitab(app.TabGroup);
            app.EngineparametersandperformanceTab.Title = 'Engine parameters and performance';
            app.PerformancesLabel = uilabel(app.EngineparametersandperformanceTab);
            app.PerformancesLabel.Text = 'Performances:';
            app.psimsLabel = uilabel(app.EngineparametersandperformanceTab);
            app.psimsLabel.Interpreter = 'latex';
            app.psimsLabel.Text = '$\psi$  [m/s]';
            app.C_TSkJgLabel = uilabel(app.EngineparametersandperformanceTab);
            app.C_TSkJgLabel.Interpreter = 'latex';
            app.C_TSkJgLabel.Text = '$C_{TS}$  [kJ/g]';
            app.DeltaK_ckJLabel = uilabel(app.EngineparametersandperformanceTab);
            app.DeltaK_ckJLabel.Interpreter = 'latex';
            app.DeltaK_ckJLabel.Text = '$\Delta K_{c}$  [kJ]';
            app.OverallLabel = uilabel(app.EngineparametersandperformanceTab);
            app.OverallLabel.Interpreter = 'latex';
            app.OverallLabel.Text = 'Overall';
            app.PropulsiveLabel = uilabel(app.EngineparametersandperformanceTab);
            app.PropulsiveLabel.Interpreter = 'latex';
            app.PropulsiveLabel.Text = 'Propulsive';
            app.ThermalLabel = uilabel(app.EngineparametersandperformanceTab);
            app.ThermalLabel.Interpreter = 'latex';
            app.ThermalLabel.Text = 'Thermal';
            app.Label_14 = uilabel(app.EngineparametersandperformanceTab);
            app.Label_14.Text = '0';
            app.Label_15 = uilabel(app.EngineparametersandperformanceTab);
            app.Label_15.Text = '0-2';
            app.Label_16 = uilabel(app.EngineparametersandperformanceTab);
            app.Label_16.Text = '2-3';
            app.Label_17 = uilabel(app.EngineparametersandperformanceTab);
            app.Label_17.Text = '4-5';
            app.Label_18 = uilabel(app.EngineparametersandperformanceTab);
            app.Label_18.Text = '5-9';
            app.Label_19 = uilabel(app.EngineparametersandperformanceTab);
            app.Label_19.Text = '3-4';
            app.T_t0Label = uilabel(app.EngineparametersandperformanceTab);
            app.T_t0Label.Interpreter = 'latex';
            app.T_t0Label.Text = '$T_{t0}$';
            app.P_t0Label = uilabel(app.EngineparametersandperformanceTab);
            app.P_t0Label.Interpreter = 'latex';
            app.P_t0Label.Text = '$P_{t0}$';
            app.pi_fsLabel = uilabel(app.EngineparametersandperformanceTab);
            app.pi_fsLabel.Interpreter = 'latex';
            app.pi_fsLabel.Text = '$\pi_{fs}$';
            app.T_t2Label = uilabel(app.EngineparametersandperformanceTab);
            app.T_t2Label.Interpreter = 'latex';
            app.T_t2Label.Text = '$T_{t2}$';
            app.P_t2Label = uilabel(app.EngineparametersandperformanceTab);
            app.P_t2Label.Interpreter = 'latex';
            app.P_t2Label.Text = '$P_{t2}$';
            app.T_t3Label = uilabel(app.EngineparametersandperformanceTab);
            app.T_t3Label.Interpreter = 'latex';
            app.T_t3Label.Text = '$T_{t3}$';
            app.P_t3Label = uilabel(app.EngineparametersandperformanceTab);
            app.P_t3Label.Interpreter = 'latex';
            app.P_t3Label.Text = '$P_{t3}$';
            app.alpha_bLabel = uilabel(app.EngineparametersandperformanceTab);
            app.alpha_bLabel.Interpreter = 'latex';
            app.alpha_bLabel.Text = '$\alpha_{b}$';
            app.P_t4Label = uilabel(app.EngineparametersandperformanceTab);
            app.P_t4Label.Interpreter = 'latex';
            app.P_t4Label.Text = '$P_{t4}$';
            app.pi_cLabel = uilabel(app.EngineparametersandperformanceTab);
            app.pi_cLabel.Interpreter = 'latex';
            app.pi_cLabel.Text = '$\pi_{c}$';
            app.T_t5Label = uilabel(app.EngineparametersandperformanceTab);
            app.T_t5Label.Interpreter = 'latex';
            app.T_t5Label.Text = '$T_{t5}$';
            app.P_t5Label = uilabel(app.EngineparametersandperformanceTab);
            app.P_t5Label.Interpreter = 'latex';
            app.P_t5Label.Text = '$P_{t5}$';
            app.alphaLabel = uilabel(app.EngineparametersandperformanceTab);
            app.alphaLabel.Interpreter = 'latex';
            app.alphaLabel.Text = '$\alpha ''$';
            app.T_9Label = uilabel(app.EngineparametersandperformanceTab);
            app.T_9Label.Interpreter = 'latex';
            app.T_9Label.Text = '$T_{9}$';
            app.P_t9Label = uilabel(app.EngineparametersandperformanceTab);
            app.P_t9Label.Interpreter = 'latex';
            app.P_t9Label.Text = '$P_{t9}$';
            app.pi_9Label = uilabel(app.EngineparametersandperformanceTab);
            app.pi_9Label.Interpreter = 'latex';
            app.pi_9Label.Text = '$\pi_{9}$';
            app.EditField = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField.Editable = 'off';
            app.EditField_2 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_2.Editable = 'off';
            app.EditField_3 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_3.Editable = 'off';
            app.EditField_4 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_4.Editable = 'off';
            app.EditField_5 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_5.Editable = 'off';
            app.EditField_6 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_6.Editable = 'off';
            app.EditField_7 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_7.Editable = 'off';
            app.EditField_8 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_8.Editable = 'off';
            app.EditField_9 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_9.Editable = 'off';
            app.EditField_10 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_10.Editable = 'off';
            app.EditField_11 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_11.Editable = 'off';
            app.EditField_12 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_12.Editable = 'off';
            app.EditField_13 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_13.Editable = 'off';
            app.EditField_14 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_14.Editable = 'off';
            app.EditField_15 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_15.Editable = 'off';
            app.EditField_16 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_16.Editable = 'off';
            app.EditField_17 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_17.Editable = 'off';
            app.V_9Label = uilabel(app.EngineparametersandperformanceTab);
            app.V_9Label.Interpreter = 'latex';
            app.V_9Label.Text = '$V_{9}$';
            app.EditField_18 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_18.Editable = 'off';
            app.EditField_19 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_19.Editable = 'off';
            app.EditField_20 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_20.Editable = 'off';
            app.EditField_21 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_21.Editable = 'off';
            app.EditField_22 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_22.Editable = 'off';
            app.EditField_23 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_23.Editable = 'off';
            app.EfficienciesLabel = uilabel(app.EngineparametersandperformanceTab);
            app.EfficienciesLabel.Text = 'Efficiencies:';
            app.KLabel_8 = uilabel(app.EngineparametersandperformanceTab);
            app.KLabel_8.Interpreter = 'latex';
            app.KLabel_8.Text = '[K]';
            app.kPaLabel = uilabel(app.EngineparametersandperformanceTab);
            app.kPaLabel.Interpreter = 'latex';
            app.kPaLabel.Text = '[kPa]';
            app.Label_20 = uilabel(app.EngineparametersandperformanceTab);
            app.Label_20.Interpreter = 'latex';
            app.Label_20.Text = '[-]';
            app.T_t4Label = uilabel(app.EngineparametersandperformanceTab);
            app.T_t4Label.Interpreter = 'latex';
            app.T_t4Label.Text = '$T_{t4}$';
            app.EditField_24 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_24.Editable = 'off';
            app.EditField_25 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.EditField_25.Editable = 'off';
            app.V_0Label = uilabel(app.EngineparametersandperformanceTab);
            app.V_0Label.Interpreter = 'latex';
            app.V_0Label.Text = '$V_{0}$';
            app.msLabel = uilabel(app.EngineparametersandperformanceTab);
            app.msLabel.Interpreter = 'latex';
            app.msLabel.Text = '[m/s]';
            app.INTERCOOLINGPARAMETERSLabel = uilabel(app.EngineparametersandperformanceTab);
            app.INTERCOOLINGPARAMETERSLabel.Text = 'INTERCOOLING PARAMETERS';
            app.T_t25EditFieldLabel = uilabel(app.EngineparametersandperformanceTab);
            app.T_t25EditFieldLabel.Interpreter = 'latex';
            app.T_t25EditFieldLabel.Text = '$T_{t2.5}$';
            app.T_t25EditField = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.T_t25Label = uilabel(app.EngineparametersandperformanceTab);
            app.T_t25Label.Interpreter = 'latex';
            app.T_t25Label.Text = '$T_{t2.5''}$';
            app.T_t25EditField_2 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.P_t25EditFieldLabel = uilabel(app.EngineparametersandperformanceTab);
            app.P_t25EditFieldLabel.Interpreter = 'latex';
            app.P_t25EditFieldLabel.Text = '$P_{t2.5}$';
            app.P_t25EditField = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.P_t25Label = uilabel(app.EngineparametersandperformanceTab);
            app.P_t25Label.Interpreter = 'latex';
            app.P_t25Label.Text = '$P_{t2.5''}$';
            app.P_t25EditField_2 = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.W_skJEditFieldLabel = uilabel(app.EngineparametersandperformanceTab);
            app.W_skJEditFieldLabel.Interpreter = 'latex';
            app.W_skJEditFieldLabel.Text = '$W_s$  [kJ]';
            app.W_skJEditField = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.M_2EditField_3Label = uilabel(app.EngineparametersandperformanceTab);
            app.M_2EditField_3Label.Interpreter = 'latex';
            app.M_2EditField_3Label.Text = '$M_{2}$';
            app.M_2EditField = uieditfield(app.EngineparametersandperformanceTab, 'numeric');
            app.PRECOOLINGPARAMETERSLabel = uilabel(app.EngineparametersandperformanceTab);
            app.PRECOOLINGPARAMETERSLabel.Text = 'PRECOOLING PARAMETERS';
            app.TrendsTab = uitab(app.TabGroup);
            app.TrendsTab.Title = 'Trends';
            app.UIAxes = uiaxes(app.TrendsTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.TrendsTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.TrendsTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.TrendsTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes2_2 = uiaxes(app.TrendsTab);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes5 = uiaxes(app.TrendsTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SIMULATOR_PC_IC_TURBOJET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end